classdef slpconverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        EnterUSDTRateEditField        matlab.ui.control.NumericEditField
        EnterUSDTRateEditFieldLabel   matlab.ui.control.Label
        EnterSLPRateEditField         matlab.ui.control.NumericEditField
        EnterSLPRateEditFieldLabel    matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        USDTtoPHPButton               matlab.ui.control.Button
        AmountofPHPEditField          matlab.ui.control.NumericEditField
        AmountofPHPEditFieldLabel     matlab.ui.control.Label
        SLPtoUSDTButton               matlab.ui.control.Button
        AmountofUSDTEditField         matlab.ui.control.NumericEditField
        AmountofUSDTEditFieldLabel    matlab.ui.control.Label
        AmountofSLPEditField          matlab.ui.control.NumericEditField
        AmountofSLPEditFieldLabel     matlab.ui.control.Label
        SLPtoUSDTtoPHPConverterLabel  matlab.ui.control.Label
        Image4                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SLPtoUSDTButton
        function SLPtoUSDTButtonPushed(app, event)
            a=app.AmountofSLPEditField.Value;
            b=app.EnterSLPRateEditField.Value;
            c=a * b;
            app.AmountofUSDTEditField.Value=c;

           
        end

        % Button pushed function: USDTtoPHPButton
        function USDTtoPHPButtonPushed(app, event)
            a=app.AmountofUSDTEditField.Value;
            b=app.EnterUSDTRateEditField.Value;
            c=a * b;
            app.AmountofPHPEditField.Value=c;
        end

        % Value changed function: EnterSLPRateEditField
        function EnterSLPRateEditFieldValueChanged(app, event)


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
           a=app.AmountofUSDTEditField.Value;
           b=app.EnterSLPRateEditField.Value;
           c=app.AmountofSLPEditField.Value;
           d=app.EnterUSDTRateEditField.Value;
           e=app.AmountofPHPEditField.Value;
           f=a* b * c * d *e * 0
           
           app.AmountofUSDTEditField.Value=f;
           app.EnterSLPRateEditField.Value=f;
           app.AmountofSLPEditField.Value=f;
           app.EnterUSDTRateEditField.Value=f;
           app.AmountofPHPEditField.Value=f;
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'download (1).png';
            app.SLPtoUSDTtoPHPConverterLabel = uilabel(app.UIFigure);
            app.SLPtoUSDTtoPHPConverterLabel.Text = 'SLP to USDT to PHP Converter';
            app.AmountofSLPEditFieldLabel = uilabel(app.UIFigure);
            app.AmountofSLPEditFieldLabel.Text = 'Amount of SLP';
            app.AmountofSLPEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountofSLPEditField.ValueDisplayFormat = '%11.10g';
            app.AmountofUSDTEditFieldLabel = uilabel(app.UIFigure);
            app.AmountofUSDTEditFieldLabel.Text = 'Amount of USDT';
            app.AmountofUSDTEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountofUSDTEditField.ValueDisplayFormat = '%11.10g';
            app.SLPtoUSDTButton = uibutton(app.UIFigure, 'push');
            app.SLPtoUSDTButton.ButtonPushedFcn = createCallbackFcn(app, @SLPtoUSDTButtonPushed, true);
            app.SLPtoUSDTButton.Text = 'SLP to USDT';
            app.AmountofPHPEditFieldLabel = uilabel(app.UIFigure);
            app.AmountofPHPEditFieldLabel.Text = 'Amount of PHP';
            app.AmountofPHPEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountofPHPEditField.ValueDisplayFormat = '%11.10g';
            app.USDTtoPHPButton = uibutton(app.UIFigure, 'push');
            app.USDTtoPHPButton.ButtonPushedFcn = createCallbackFcn(app, @USDTtoPHPButtonPushed, true);
            app.USDTtoPHPButton.Text = 'USDT to PHP';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.EnterSLPRateEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSLPRateEditFieldLabel.Text = 'Enter SLP Rate ($)';
            app.EnterSLPRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterSLPRateEditField.ValueDisplayFormat = '%11.10g';
            app.EnterSLPRateEditField.ValueChangedFcn = createCallbackFcn(app, @EnterSLPRateEditFieldValueChanged, true);
            app.EnterUSDTRateEditFieldLabel = uilabel(app.UIFigure);
            app.EnterUSDTRateEditFieldLabel.Text = 'Enter USDT Rate ';
            app.EnterUSDTRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterUSDTRateEditField.ValueDisplayFormat = '%11.10g';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'SLP.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'tether-usdt-logo.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'peso.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slpconverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end